classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        DropDownLabel   matlab.ui.control.Label
        DropDown        matlab.ui.control.DropDown
        DropDown2Label  matlab.ui.control.Label
        DropDown2       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        iller
        ilceler
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load il-ilce.mat iller ilceler
            app.DropDownLabel.Text = 'İl';
            app.DropDown2Label.Text = 'İlçe';
            app.iller = iller;
            app.ilceler = ilceler
            app.DropDown.Items = ['Seçiniz';app.iller.il_adi];
            app.DropDown.ItemsData = [0;app.iller.id];


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value; 
            
            ilceleri = app.ilceler(app.iller.id==value,:);
            app.DropDown2.Items = ['Seçiniz';ilceleri.ilce_adi];
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown2Label = uilabel(app.UIFigure);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end