classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        Button2      matlab.ui.control.Button
        UITable      matlab.ui.control.Table
        NufusButton  matlab.ui.control.Button
        VeriButton   matlab.ui.control.Button
        Button3      matlab.ui.control.Button
        Label        matlab.ui.control.Label
        Button4      matlab.ui.control.Button
    end

    
    properties (Access = public)
        tabloT % tabloT tablosu
        tabloN % nüfus tablosu
        ort
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Benim Uygulamam';
            load tablom.mat
            app.tabloT = T;
           
            load census.mat cdate pop
            app.tabloN = table(cdate,pop);
            app.UITable.Data = app.tabloT;
            app.UITable.ColumnName = T.Properties.VariableNames;
            app.ort = mean(T.boy);
            app.Label.Text = 'sonuç = ';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIFigure.Color = rand(1,3);
            pause(1);
%             app.Button2Pushed()
%             Button2Pushed(app,event);
            app.Button2.ButtonPushedFcn(app,event);
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            app.UIFigure.Color = 'Blue';
        end

        % Button pushed function: NufusButton
        function NufusButtonPushed(app, event)
            
            app.UITable.Data = app.tabloN;
        end

        % Button pushed function: VeriButton
        function VeriButtonPushed(app, event)
%             load tablom.mat
            app.UITable.Data = app.tabloT;
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
                app.UITable.Data = head(app.tabloT,3)
%             app.UITable.Data = head(app.UITable.Data,3);
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
%            app.Label.Text = num2str(mean(app.tabloT.boy));
             app.UITable.Data = app.tabloT.kilo<75 & app.tabloT.sigara == true ;
             app.Label.Text = num2str(mean(app.tabloT.boy>175 & (app.tabloT.sigara == true)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.NufusButton = uibutton(app.UIFigure, 'push');
            app.NufusButton.ButtonPushedFcn = createCallbackFcn(app, @NufusButtonPushed, true);
            app.NufusButton.Text = 'Nufus';
            app.VeriButton = uibutton(app.UIFigure, 'push');
            app.VeriButton.ButtonPushedFcn = createCallbackFcn(app, @VeriButtonPushed, true);
            app.VeriButton.Text = 'Veri';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = 'Button3';
            app.Label = uilabel(app.UIFigure);
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = 'Button4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end