classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        KnobLabel       matlab.ui.control.Label
        Knob            matlab.ui.control.Knob
        Knob2Label      matlab.ui.control.Label
        Knob2           matlab.ui.control.DiscreteKnob
        LampLabel       matlab.ui.control.Label
        Lamp            matlab.ui.control.Lamp
        Lamp2Label      matlab.ui.control.Label
        Lamp2           matlab.ui.control.Lamp
        SwitchLabel     matlab.ui.control.Label
        Switch          matlab.ui.control.ToggleSwitch
        Button          matlab.ui.control.Button
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        SpinnerLabel    matlab.ui.control.Label
        Spinner         matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        v % Description
        gr % Description
        ck = 2;
        cb= ':';
        cr= 'red';
        ib='o';
        ibyk =24;
        isnr = rand(1,3);
        isdr = rand(1,3);
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.v = value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.Title.String = strcat('y=' ,app.v, ' fonksiyonu');
            m = app.EditField.Value;
            c = strcat('@(x)',m);
            a = str2func(c);
            
            app.gr = fplot(app.UIAxes,a);
            app.gr.LineWidth = app.ck;
            app.gr.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob2Label = uilabel(app.UIFigure);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.UIFigure, 'discrete');
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2Label = uilabel(app.UIFigure);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end