classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditFieldLabel   matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        EditField2Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.NumericEditField
        Button           matlab.ui.control.Button
        CheckBox         matlab.ui.control.CheckBox
        ButtonGroup      matlab.ui.container.ButtonGroup
        i1Button         matlab.ui.control.RadioButton
        i2Button         matlab.ui.control.RadioButton
        i3Button         matlab.ui.control.RadioButton
        DropDownLabel    matlab.ui.control.Label
        ilDropDown       matlab.ui.control.DropDown
        DropDown2Label   matlab.ui.control.Label
        ilceDropDown     matlab.ui.control.DropDown
        DropDown3Label   matlab.ui.control.Label
        semtlerDropDown  matlab.ui.control.DropDown
        Button4          matlab.ui.control.Button
    end

    
    properties (Access = private)
        iller
        ilceler
        semtler% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load il-ilce.mat iller ilceler semtler
            app.iller = iller
            app.ilceler = ilceler
            app.semtler = semtler
            app.DropDownLabel.Text = 'iller'
            app.ilDropDown.Items = ["Seçiniz";app.iller.il_adi];
            app.ilDropDown.ItemsData = [0;app.iller.id]
            
            app.DropDown2Label.Text = "İlçeler";
            app.ilceDropDown.Items ={};
            
            app.DropDown3Label.Text = "Semtler";
            app.semtlerDropDown.Items ={};
            
            
            app.EditFieldLabel.Text="Yazı";
            app.CheckBox.Text= 'simge goster';
            app.ButtonGroup.BackgroundColor = '#bde0fe';
            app.ButtonGroup.BorderType = 'none';
            app.i1Button.Text = 'Icon 1';
            app.i2Button.Text = 'Icon 2';
            app.i3Button.Text = 'Icon 3';
            app.ButtonGroup.Title = 'Icon Seçiniz';
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            d = app.EditField.Value;
            app.Button.Text = d;
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            d = event.Value;
            app.Button.Text = d;
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            d = app.EditField2.Value;
            rs = randi([1,d],1,1);
            app.Button.FontSize = rs;
            app.Button.Text = num2str(d);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if (value == true)
                app.Button.Icon = 'FontAwesome/fa-american-sign-language-interpreting';
            
            else
                app.Button.Icon = '';
                app.ButtonGroup.Visiable = false;
            end
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            sb = app.ButtonGroup.SelectedObject;
            if (sb == app.i1Button)
                 app.Button.Icon = 'FontAwesome/fa-500px'
            elseif (sb == app.i2Button)
                 app.Button.Icon = 'FontAwesome/fa-american-sign-language-interpreting'
            elseif (sb ==app.i3Button)
                 app.Button.Icon = 'FontAwesome/fa-linux'
            end
            
            
        end

        % Value changed function: ilDropDown
        function ilDropDownValueChanged(app, event)
            deger = app.ilDropDown.Value;
            ilceleri = app.ilceler(app.ilceler.il_id==deger,:);
            app.ilceDropDown.Items = ["Seçiniz";ilceleri.ilce_adi];
            app.ilceDropDown.ItemsData = [0;ilceleri.id];
            
            
        end

        % Value changed function: ilceDropDown
        function ilceDropDownValueChanged(app, event)
            deger = app.ilceDropDown.Value;
            semtleri = app.semtler(app.semtler.ilce_id==deger,:);
            app.semtlerDropDown.Items = ["Seçiniz";semtleri.semt_adi];
            app.semtlerDropDown.ItemsData = [0;semtleri.id];
            
            
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            % https://www.google.com/search?p=istanbul+aksaray
            il_id = app.ilDropDown.Value;
            ilce_id = app.ilceDropDown.Value;
            il_ismi = app.iller(app.iller.id == il_id,2);
            ilce_ismi = app.ilceler(app.ilceler.id == ilce_id,3);
            link = sprintf('https://www.google.com/search?p=%s+%s',il_ismi{1,1},ilce_ismi{1,1})
            web(link,'-browser');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.i1Button = uiradiobutton(app.ButtonGroup);
            app.i1Button.Text = 'Button';
            app.i1Button.Value = true;
            app.i2Button = uiradiobutton(app.ButtonGroup);
            app.i2Button.Text = 'Button2';
            app.i3Button = uiradiobutton(app.ButtonGroup);
            app.i3Button.Text = 'Button3';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.ilDropDown = uidropdown(app.UIFigure);
            app.ilDropDown.ValueChangedFcn = createCallbackFcn(app, @ilDropDownValueChanged, true);
            app.DropDown2Label = uilabel(app.UIFigure);
            app.DropDown2Label.Text = 'Drop Down2';
            app.ilceDropDown = uidropdown(app.UIFigure);
            app.ilceDropDown.ValueChangedFcn = createCallbackFcn(app, @ilceDropDownValueChanged, true);
            app.DropDown3Label = uilabel(app.UIFigure);
            app.DropDown3Label.Text = 'Drop Down3';
            app.semtlerDropDown = uidropdown(app.UIFigure);
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = 'Button4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end