classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UITable        matlab.ui.control.Table
        Button         matlab.ui.control.Button
        DropDownLabel  matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        Label          matlab.ui.control.Label
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button_2       matlab.ui.control.RadioButton
        Button2        matlab.ui.control.RadioButton
        Button3        matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        s_tar
        s
        T
        % Description
         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load census.mat 
            app.s_tar = cdate;
            app.s = pop;
            app.T = [cdate pop];
            app.UITable.Data = app.T;
            app.UITable.ColumnName = ["cdate" ;"pop"];
            app.DropDown.Items = ["Yıl seçiniz";app.s_tar];
            app.DropDown.ItemsData = [0;app.s];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Label.Text = num2str(app.d);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            yT = app.T(app.T == value ,:);
            app.UITable.Data = yT;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end