classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        DatePickerLabel  matlab.ui.control.Label
        DatePicker       matlab.ui.control.DatePicker
        SliderLabel      matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        SpinnerLabel     matlab.ui.control.Label
        Spinner          matlab.ui.control.Spinner
        EditFieldLabel   matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DatePickerLabel.Text = "Seçiniz";
            % app.DatePicker.Limits = [datetime('today'),datetime(2024,7,25)];
            
            % app.DatePicker.Limits = [datetime('today'),datetime('today')+15];
            
            % app.DatePicker.DisabledDates = [datetime(2024,5,19)];
            % app.DatePicker.DisabledDaysOfWeek = [3] haftanın 1. gününü
            % pazar alıyor
            
            app.DatePicker.DisplayFormat = "dd.mm.yyyy";
            % app.DatePicker.Tooltip = "Tarih Seçiniz ";
            app.DatePicker.Editable = false;
            
            %app.Spinner.Limits = [2,10];
            app.Spinner.Step = 2;
            
            app.Slider.Orientation = 'vertical';
            app.Slider.Limits = [10,50];
            app.Slider.MajorTicks = [10,20,40,50];  % aralıklardaki değerleri belirlememizi sağlıyor
            % app.Slider.MajorTickLabels = {'A','B','C','D'};
            app.Slider.MajorTickLabels = {'düşük','orta','yüksek','çok yüksek'};
            
            % app.Slider.MinorTicks = []; % yaparsak direk sadece majorler var
            app.Slider.MinorTicks = [1,2,10,20];
            
            
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            value = app.DatePicker.Value;
                     
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            app.EditField.Value = value;
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner.Value = changingValue;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.EditField.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end