classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Button          matlab.ui.control.Button
        SpinnerLabel    matlab.ui.control.Label
        SpinnerRed      matlab.ui.control.Spinner
        Spinner_2Label  matlab.ui.control.Label
        SpinnerGreen    matlab.ui.control.Spinner
        Spinner_3Label  matlab.ui.control.Label
        SpinnerBlue     matlab.ui.control.Spinner
        ButtonRenkD     matlab.ui.control.Button
        SliderLabel     matlab.ui.control.Label
        SliderR         matlab.ui.control.Slider
        SliderLabel_2   matlab.ui.control.Label
        SliderG         matlab.ui.control.Slider
        SliderLabel_3   matlab.ui.control.Label
        SliderB         matlab.ui.control.Slider
    end

    
    properties (Access = public)
        renk % Description
    end
    
    methods (Access = private)
        
        function results = herseyi_yap(app)
            app.Button.BackgroundColor = app.renk;
            app.SpinnerRed.Value = app.renk(1);
            app.SpinnerGreen.Value = app.renk(2);
            app.SpinnerBlue.Value = app.renk(3);
            
            app.SliderR.Value = app.renk(1);
            app.SliderG.Value = app.renk(2);
            app.SliderB.Value = app.renk(3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Button.Text = '';
            app.ButtonRenkDPushed;
            app.Spinner_3Label.Text = 'Mavi';
            app.Spinner_2Label.Text = 'Yeşil';
            app.SpinnerLabel.Text = 'Kırmızı';
            app.ButtonRenkD.Text = 'Renk Degis';
            
            app.SpinnerBlue.Step = 0.05;
            app.SpinnerRed.Step = 0.05;
            app.SpinnerGreen.Step = 0.05;
            
            app.SpinnerBlue.Limits = [0,1];
            app.SpinnerRed.Limits = [0,1];
            app.SpinnerGreen.Limits = [0,1];
            
            app.SliderLabel.Text = "R";
            app.SliderR.Orientation = 'vertical';
            app.SliderR.Limits = [0,1];
            app.SliderR.MajorTicks = [0,0.4,0.6,0.8,1];
            app.SliderR.FontColor = 'red';
            
            app.SliderLabel_2.Text = "G";
            app.SliderG.Orientation = 'vertical';
            app.SliderG.Limits = [0,1];
            app.SliderG.MajorTicks = [0,0.4,0.6,0.8,1];
            app.SliderG.FontColor = 'green';
            
            app.SliderLabel_3.Text = "B";
            app.SliderB.Orientation = 'vertical';
            app.SliderB.Limits = [0,1];
            app.SliderB.MajorTicks = [0,0.4,0.6,0.8,1];
            app.SliderB.FontColor = 'blue';
            
        end

        % Button pushed function: ButtonRenkD
        function ButtonRenkDPushed(app, event)
            app.renk = rand(1,3);
            herseyi_yap(app);
        end

        % Value changed function: SpinnerRed
        function SpinnerRedValueChanged(app, event)
            value = app.SpinnerRed.Value;
            app.renk(1) = value;
            herseyi_yap(app);
        end

        % Value changed function: SpinnerGreen
        function SpinnerGreenValueChanged(app, event)
            value = app.SpinnerGreen.Value;
            app.renk(2) = value;
            herseyi_yap(app);
        end

        % Value changed function: SpinnerBlue
        function SpinnerBlueValueChanged(app, event)
            value = app.SpinnerBlue.Value;
            app.renk(3) = value;
            app.Button.BackgroundColor = app.renk;
        end

        % Value changing function: SliderR
        function SliderRValueChanging(app, event)
            changingValue = event.Value;
            app.renk(1) = changingValue;
            herseyi_yap(app);
        end

        % Value changing function: SliderG
        function SliderGValueChanging(app, event)
            changingValue = event.Value;
            app.renk(2) = changingValue;
            herseyi_yap(app);
        end

        % Value changing function: SliderB
        function SliderBValueChanging(app, event)
            changingValue = event.Value;
            app.renk(3) = changingValue;
            herseyi_yap(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.SpinnerRed = uispinner(app.UIFigure);
            app.SpinnerRed.ValueChangedFcn = createCallbackFcn(app, @SpinnerRedValueChanged, true);
            app.Spinner_2Label = uilabel(app.UIFigure);
            app.Spinner_2Label.Text = 'Spinner';
            app.SpinnerGreen = uispinner(app.UIFigure);
            app.SpinnerGreen.ValueChangedFcn = createCallbackFcn(app, @SpinnerGreenValueChanged, true);
            app.Spinner_3Label = uilabel(app.UIFigure);
            app.Spinner_3Label.Text = 'Spinner';
            app.SpinnerBlue = uispinner(app.UIFigure);
            app.SpinnerBlue.ValueChangedFcn = createCallbackFcn(app, @SpinnerBlueValueChanged, true);
            app.ButtonRenkD = uibutton(app.UIFigure, 'push');
            app.ButtonRenkD.ButtonPushedFcn = createCallbackFcn(app, @ButtonRenkDPushed, true);
            app.ButtonRenkD.Text = 'Button2';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.SliderR = uislider(app.UIFigure);
            app.SliderR.ValueChangingFcn = createCallbackFcn(app, @SliderRValueChanging, true);
            app.SliderLabel_2 = uilabel(app.UIFigure);
            app.SliderLabel_2.Text = 'Slider';
            app.SliderG = uislider(app.UIFigure);
            app.SliderG.ValueChangingFcn = createCallbackFcn(app, @SliderGValueChanging, true);
            app.SliderLabel_3 = uilabel(app.UIFigure);
            app.SliderLabel_3.Text = 'Slider';
            app.SliderB = uislider(app.UIFigure);
            app.SliderB.ValueChangingFcn = createCallbackFcn(app, @SliderBValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end