classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Image         matlab.ui.control.Image
        Button        matlab.ui.control.Button
        ListBoxLabel  matlab.ui.control.Label
        ListBox       matlab.ui.control.ListBox
        Image_2       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Color = rand(1,3);
            app.Image.ImageSource = 'Spo/OIG4.jpeg';
            app.ListBox.Items = {'fit','fill','none','scaledown','scaleup','stretch'};
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % app.Image.ImageSource = 'Spo/OIG4.jpeg';  %bir alt klasörde ise
             app.Image.ImageSource = 'Spo/resim2.jpg';    % aynı klaösrde ise
            % app.Image.ImageSource = './resim3.jpg';   üst klasörden
            % çağırmak için
             app.Image_2.ImageSource = 'Spo/res3.jpg';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.Image.ScaleMethod = value;
            app.Image_2.ScaleMethod = value;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
           app.UIFigure.Color = rand(1,3); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.Image_2 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end