classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.StateButton
        Button2        matlab.ui.control.Button
        ButtonGroup    matlab.ui.container.ButtonGroup
        K              matlab.ui.control.ToggleButton
        M              matlab.ui.control.ToggleButton
        Y              matlab.ui.control.ToggleButton
        TextAreaLabel  matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        Tree           matlab.ui.container.Tree
        Node           matlab.ui.container.TreeNode
        Node2          matlab.ui.container.TreeNode
        Node3          matlab.ui.container.TreeNode
        Node4          matlab.ui.container.TreeNode
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.K.Text = 'Kırmızı';
          app.Y.Text = 'Yeşil';
          app.M.Text = 'Mavi';
          app.TextArea.Value = {'aaaa';'bbbb'};   % text area nın satırlarını biz doldurmak içiin
          app.Tree.Multiselect = 1;
          app.Node3.Icon = 'Spo/resim2.jpg';
          app.Node2.Icon = 'Spo/resim2.jpg';
          app.Node4.Icon = 'Spo/resim2.jpg';
          
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if (value == 1)
                app.UIFigure.Color = 'red';
            else
                app.UIFigure.Color = [0.94,0.94,0.94];
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.K
                app.UIFigure.Color = 'red';
            elseif selectedButton == app.M
                app.UIFigure.Color = 'blue';
            elseif selectedButton == app.Y
                app.UIFigure.Color = 'green';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Button2';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.K = uitogglebutton(app.ButtonGroup);
            app.K.Text = 'Button';
            app.K.Value = true;
            app.M = uitogglebutton(app.ButtonGroup);
            app.M.Text = 'Button2';
            app.Y = uitogglebutton(app.ButtonGroup);
            app.Y.Text = 'Button3';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.Tree = uitree(app.UIFigure);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end