classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button           matlab.ui.control.Button
        ButtonGroup      matlab.ui.container.ButtonGroup
        Icon1            matlab.ui.control.RadioButton
        Icon2            matlab.ui.control.RadioButton
        Icon3            matlab.ui.control.RadioButton
        CheckBox         matlab.ui.control.CheckBox
        EditFieldLabel   matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        EditField2Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.NumericEditField
        DropDownLabel    matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CheckBox.Text = 'Iconları Göster';
            app.ButtonGroup.Title = 'Iconlar';
            app.Icon1.Text = 'Icon1';
            app.Icon2.Text = 'Icon2';
            app.Icon3.Text = 'Icon3';
            app.ButtonGroup.Visible = 0;
            
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            d = app.EditField2.Value;
            app.Button.FontSize = d;
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            d = event.Value;
            app.Button.Text = d;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            v = app.CheckBox.Value;
            if (v == 1)
                app.Button.Icon = 'FontAwesome/fa-child.png';
                app.ButtonGroup.Visible = 1;
            else
                app.ButtonGroup.Visible = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Icon1 = uiradiobutton(app.ButtonGroup);
            app.Icon1.Text = 'Button';
            app.Icon1.Value = true;
            app.Icon2 = uiradiobutton(app.ButtonGroup);
            app.Icon2.Text = 'Button2';
            app.Icon3 = uiradiobutton(app.ButtonGroup);
            app.Icon3.Text = 'Button3';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end