classdef uyg11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        Button          matlab.ui.control.Button
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        SpinnerLabel    matlab.ui.control.Label
        Spinner         matlab.ui.control.Spinner
        LampLabel       matlab.ui.control.Label
        Lamp            matlab.ui.control.Lamp
        Lamp_2Label     matlab.ui.control.Label
        Lamp_2          matlab.ui.control.Lamp
        Switch2Label    matlab.ui.control.Label
        Switch2         matlab.ui.control.ToggleSwitch
        KnobLabel       matlab.ui.control.Label
        Knob            matlab.ui.control.DiscreteKnob
        Knob2Label      matlab.ui.control.Label
        Knob2           matlab.ui.control.Knob
        ListBoxLabel    matlab.ui.control.Label
        ListBox         matlab.ui.control.ListBox
    end

    
    properties (Access = public)
       gr 
       ck = 2;
       cb = ':';
       cr = 'red';
       ib = 'o';
       ibyk = 24;
       isnr = rand(1,3);
       isdr = rand(1,3);
       % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Button.Text = 'Grafik Çiz';
          
            app.EditFieldLabel.Text = 'grafik:';
            app.EditField.Value = 'sin(x)';


            app.SpinnerLabel.Text = 'İşaretçi Boyutu';
            app.Spinner.Value = 24;
            
            
            app.LampLabel.Text = 'işaretçi Sınır Rengi';
            app.Lamp_2Label.Text = 'İŞaretçi Dolgu Rengi';
            %app.Lamb.Color = app.isnr;
            %app.Lamp_2.Color = app.isdr;
            
            
            app.Switch2Label.Text = 'Izgara Çizgileri';
            app.Switch2.Items ={'Kapalı','Açık'};
            app.Switch2.ItemsData = [0,1];
            app.KnobLabel.Text = 'İşaretçi Biçimi';
            app.Knob.Items = {'o','p','d','*','+'};
            app.ListBoxLabel.Text = 'Çizgi Biçimi';
            app.ListBox.Items =  {':','-','-.','--','none'};
            app.Knob2Label.Text = 'Çİzgi Kalınlığı';
            app.Knob2.Value = app.ck;
            app.Knob2.Limits = [1,10];
           % app.Knob2.MajorTickLabels = 


              app.ButtonPushed
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             x = 0:0.3:2*pi;
%             y = sin(x);
%             plot(app.UIAxes,x,y);
              m = app.EditField.Value;
              c = strcat('@(x)',m);
              a = str2func(c);
              
              
              app.UIAxes.Title.String = strcat('y=',m,'fonksiyonu');
              app.gr = fplot(app.UIAxes,a);
              app.gr.LineWidth = app.ck      ;             %d:1
              app.gr.LineStyle = app.cb;
              app.gr.Marker = app.ib;
              app.gr.Color = app.cr;
              app.gr.MarkerSize = app.ibyk; % d:6
              app.gr.MarkerEdgeColor = app.isnr;
              app.gr.MarkerFaceColor = app.isdr;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            app.ibyk = value;
           app.gr.MarkerSize = value;
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
           if (value == 1)
               grid(app.UIAxes,"minor");
           else
               grid(app.UIAxes,"off")
           end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.ib = value;
            app.gr.Marker = value;
        end

        % Value changed function: Knob2
        function Knob2ValueChanged(app, event)
            value = app.Knob2.Value;
            app.ck = value;
            app.gr.LineWidth = value;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.cb = value;
            app.gr.LineStyle = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = 'Lamp';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob2Label = uilabel(app.UIFigure);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.UIFigure, 'continuous');
            app.Knob2.ValueChangedFcn = createCallbackFcn(app, @Knob2ValueChanged, true);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uyg11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end