classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditFieldLabel   matlab.ui.control.Label
        EditFieldT       matlab.ui.control.EditField
        EditField2Label  matlab.ui.control.Label
        EditField2N      matlab.ui.control.NumericEditField
        Button           matlab.ui.control.Button
        CheckBox         matlab.ui.control.CheckBox
        DropDownLabel    matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        ListBoxLabel     matlab.ui.control.Label
        ListBox          matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditFieldT.Editable = false;
            
            app.EditField2N.Limits = [12,37];
            app.EditField2N.LowerLimitInclusive = false;
            app.EditField2N.UpperLimitInclusive = false;
            app.EditField2N.RoundFractionalValues = true;
            app.DropDown.Editable = true;
            
            app.ListBox.Multiselect = true;
            
            app.ListBox.Items = {'red','blue','green'};
            
           k = rand(1,1)
           assignin("base","k2",k); %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% sınav
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.EditField2N.Editable = false;
            app.EditFieldT.Editable = false;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            
            if value == 1 
                app.EditFieldT.Editable = true;
            else
                app.EditFieldT.Editable = false;
                app.EditFieldT.Value = '';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.DropDown.Items
            app.DropDown.ItemsData
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            for i =1:size(value,2)
                app.UIFigure.Color = value{1,i};
                pause(1);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditFieldT = uieditfield(app.UIFigure, 'text');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2N = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end