classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITable            matlab.ui.control.Table
        RenkButton         matlab.ui.control.Button
        VeriKstlamaButton  matlab.ui.control.Button
        OrtalamaButton     matlab.ui.control.Button
        SnrlamaButton      matlab.ui.control.Button
        SifirlamaButton    matlab.ui.control.Button
        Label              matlab.ui.control.Label
    end

    
    properties (Access = private)
        tabloT; 
        tabloN;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load tablom.mat ;
            app.UIFigure.Name = 'Benim Uygulamam';
            app.tabloT= T;
            app.UITable.Data = app.tabloT;
            app.UITable.ColumnName = T.Properties.VariableNames;
            
        end

        % Callback function
        function UITableCellEdit(app, event)
          
            
        end

        % Button pushed function: RenkButton
        function RenkButtonPushed(app, event)
            app.UIFigure.Color = rand(1,3);
        end

        % Button pushed function: VeriKstlamaButton
        function VeriKstlamaButtonPushed(app, event)
            app.UITable.Data = app.tabloT(:,2);
        end

        % Button pushed function: SifirlamaButton
        function SifirlamaButtonPushed(app, event)
            app.UITable.Data = app.tabloT;
            app.UITable.ColumnName = app.tabloT.Properties.VariableNames;
        end

        % Button pushed function: OrtalamaButton
        function OrtalamaButtonPushed(app, event)
            yT = app.tabloT(app.tabloT.sigara == false & app.tabloT.kilo < 75,:);
            app.UITable.Data = yT;
            app.Label.Text = num2str(mean(yT.boy));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RenkButton = uibutton(app.UIFigure, 'push');
            app.RenkButton.ButtonPushedFcn = createCallbackFcn(app, @RenkButtonPushed, true);
            app.RenkButton.Text = 'Renk';
            app.VeriKstlamaButton = uibutton(app.UIFigure, 'push');
            app.VeriKstlamaButton.ButtonPushedFcn = createCallbackFcn(app, @VeriKstlamaButtonPushed, true);
            app.VeriKstlamaButton.Text = 'Veri Kısıtlama';
            app.OrtalamaButton = uibutton(app.UIFigure, 'push');
            app.OrtalamaButton.ButtonPushedFcn = createCallbackFcn(app, @OrtalamaButtonPushed, true);
            app.OrtalamaButton.Text = 'Ortalama';
            app.SnrlamaButton = uibutton(app.UIFigure, 'push');
            app.SnrlamaButton.Text = 'Sınırlama';
            app.SifirlamaButton = uibutton(app.UIFigure, 'push');
            app.SifirlamaButton.ButtonPushedFcn = createCallbackFcn(app, @SifirlamaButtonPushed, true);
            app.SifirlamaButton.Text = {'Sifirlama'; ''};
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end