classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GaugeLabel   matlab.ui.control.Label
        Gauge        matlab.ui.control.NinetyDegreeGauge
        Gauge2Label  matlab.ui.control.Label
        Gauge2       matlab.ui.control.Gauge
        Gauge3Label  matlab.ui.control.Label
        Gauge3       matlab.ui.control.LinearGauge
        Gauge4Label  matlab.ui.control.Label
        Gauge4       matlab.ui.control.SemicircularGauge
        Button       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %app.Gauge.Limits = [1,5];
            %for i = 0:100
            %    app.Gauge2.Value = i;
            %    pause(0.1)
            %end
            
            app.Gauge4Label.Text = 'Basınç';
            app.Gauge2.ScaleColors = {"red","red","blue"};
            %app.Gauge4.ScaleColors = [1,1,0;0.3,0.2,0.1; 1,0,0];
            app.Gauge3.ScaleColors = rand(10,3);
            
            
            app.Gauge4.ScaleColors = colormap(app.UIFigure,"jet");
            
            app.Gauge.ScaleColors = {"red","blue"};
            app.Gauge.ScaleColorLimits = [1,10;10,100];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'ninetydegree');
            app.Gauge2Label = uilabel(app.UIFigure);
            app.Gauge2Label.Text = 'Gauge2';
            app.Gauge2 = uigauge(app.UIFigure, 'circular');
            app.Gauge3Label = uilabel(app.UIFigure);
            app.Gauge3Label.Text = 'Gauge3';
            app.Gauge3 = uigauge(app.UIFigure, 'linear');
            app.Gauge4Label = uilabel(app.UIFigure);
            app.Gauge4Label.Text = 'Gauge4';
            app.Gauge4 = uigauge(app.UIFigure, 'semicircular');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end