classdef giris < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button            matlab.ui.control.Button
        EditFieldLabel    matlab.ui.control.Label
        User              matlab.ui.control.EditField
        Image             matlab.ui.control.Image
        EditField_2Label  matlab.ui.control.Label
        Pass              matlab.ui.control.EditField
        Label             matlab.ui.control.Label
    end

    
    properties (Access = public)
        k % kullanıcılar tablosu
        k_id
        k_adi
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load data.mat kullanicilar ;
            app.k = kullanicilar;
            
            
            app.EditFieldLabel.Text = "Kullanıcı";
            app.EditField_2Label.Text = "Şifre";
            app.Image.ImageSource = "resimler/giris_resim.jpg";
            
            app.UIFigure.Color = [0.60,0.83,0.75];
            app.UIFigure.Name = "Giriş";
            app.Button.Text = "deneme";
            app.Button.Text = "Giriş Yap!";
            app.Label.Text = "";
            app.Label.FontColor = 'red';
            app.Label.FontWeight = 'bold';
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            kadi = app.User.Value;
            sifre = app.Pass.Value;
            kullanici = app.k(app.k{:,2} == kadi,:);
            
            if (size(kullanici,1) == 0)
                app.Label.Text = "Geçersiz Kullanıcı";
            else
                if(strcmp(kullanici{1,3},sifre))
                    app.Label.Text = "OK";
                    app.k_id = kullanici{1,1};
                    app.k_adi = kullanici{1,2};
                    resim_goster(app);
                    delete(app.UIFigure)
                    
                else
                    app.Label.Text ="Geçersiz Şifre";
                end


            end


        end

        % Value changed function: User
        function UserValueChanged(app, event)
            value = app.User.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.User = uieditfield(app.UIFigure, 'text');
            app.User.ValueChangedFcn = createCallbackFcn(app, @UserValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Edit Field';
            app.Pass = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = giris

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end