classdef resim_goster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button           matlab.ui.control.Button
        Image            matlab.ui.control.Image
        KnobLabel        matlab.ui.control.Label
        Knob             matlab.ui.control.DiscreteKnob
        EditFieldLabel   matlab.ui.control.Label
        EditFieldN       matlab.ui.control.NumericEditField
        EditField2Label  matlab.ui.control.Label
        EditFieldT       matlab.ui.control.EditField
        Label            matlab.ui.control.Label
        ImageY1          matlab.ui.control.Image
        ImageY2          matlab.ui.control.Image
        ImageY3          matlab.ui.control.Image
        ImageY4          matlab.ui.control.Image
        ImageY5          matlab.ui.control.Image
    end

    
    properties (Access = public)
        puanlar
        resimler
         % Description
        
        aktif_resim
    end
    
    methods (Access = public)
        
        function tum_ortalama(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, giris_app)
            app.UIFigure.Name = "Resim Goster";
%            app.Button.Text = giris_app.User.Value;

            load data.mat resimler puanlar;
            app.puanlar = puanlar;
            app.resimler = resimler;
            
            app.KnobLabel.Text = "";
            
            app.EditFieldT.Value = "Kullanıcı Adı";
            app.EditFieldN.Value = 5;
            
            app.Knob.Items = cellstr(string(1:size(resimler,1)));
            app.Knob.ItemsData = app.resimler{:,1};
            
            app.KnobValueChanged


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            giris();
            delete(app.UIFigure)
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            resim_satir = app.resimler(app.resimler{:,1} == value,:);
            
            app.Label.Text = resim_satir{1,2};
            
            app.Image.ImageSource = strcat("resimler/",resim_satir{1,3});
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Image = uiimage(app.UIFigure);
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditFieldN = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditFieldT = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.ImageY1 = uiimage(app.UIFigure);
            app.ImageY2 = uiimage(app.UIFigure);
            app.ImageY3 = uiimage(app.UIFigure);
            app.ImageY4 = uiimage(app.UIFigure);
            app.ImageY5 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resim_goster(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end